classdef ChessGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChessUIFigure                matlab.ui.Figure
        YellowTraingleDropDownLabel  matlab.ui.control.Label
        YellowTriangleDropDown       matlab.ui.control.DropDown
        YellowSquareDropDownLabel    matlab.ui.control.Label
        YellowSquareDropDown         matlab.ui.control.DropDown
        YellowCircleDropDownLabel    matlab.ui.control.Label
        YellowCircleDropDown         matlab.ui.control.DropDown
        RedTriangleDropDownLabel     matlab.ui.control.Label
        RedTriangleDropDown          matlab.ui.control.DropDown
        RedSquareDropDownLabel       matlab.ui.control.Label
        RedSquareDropDown            matlab.ui.control.DropDown
        RedCircleDropDownLabel       matlab.ui.control.Label
        RedCircleDropDown            matlab.ui.control.DropDown
        BlueTriangleDropDownLabel    matlab.ui.control.Label
        BlueTriangleDropDown         matlab.ui.control.DropDown
        BlueSquareDropDownLabel      matlab.ui.control.Label
        BlueSquareDropDown           matlab.ui.control.DropDown
        BlueCircleDropDownLabel      matlab.ui.control.Label
        BlueCircleDropDown           matlab.ui.control.DropDown
        GreenTriangleDropDownLabel   matlab.ui.control.Label
        GreenTriangleDropDown        matlab.ui.control.DropDown
        GreenSquareDropDownLabel     matlab.ui.control.Label
        GreenSquareDropDown          matlab.ui.control.DropDown
        GreenCircleDropDownLabel     matlab.ui.control.Label
        GreenCircleDropDown          matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
        AddPiecesLabel               matlab.ui.control.Label
        MovePieceButton              matlab.ui.control.Button
        RemovePieceButton            matlab.ui.control.Button
    end


    properties (Access = public)
        GB = [0 0 0 0 0 0 0 0 ; 0 0 0 0 0 0 0 0
              0 0 0 0 0 0 0 0 ; 0 0 0 0 0 0 0 0 
              0 0 0 0 0 0 0 0 ; 0 0 0 0 0 0 0 0
              0 0 0 0 0 0 0 0 ; 0 0 0 0 0 0 0 0];
    end


    methods (Access = private)

        % Code that executes after component creation
        function gameStartFN(app)

        end

        % Value changed function: YellowTriangleDropDown
        function YellowTriAdd(app, event)
            piece = app.YellowTriangleDropDown.Value;
            board = app.UIAxes;
            marker = '^';
            color = 'Yellow';
            hold(board,'on');

            prompt = {'Enter x coordinate:','Enter y coordinate:'};
            title = 'Select an open space';
            answer = inputdlg(prompt,title);
            X = str2num(answer{1});
            Y = str2num(answer{2});
            while (X>8 || X<1 || Y>8 || Y<1) || (app.GB(X,Y)==1)
                answer = inputdlg(prompt,title);
                X = str2num(answer{1});
                Y = str2num(answer{2});
            end
            app.GB(X,Y) = 1;
            plot(board,X-0.5,Y-0.5,'marker',marker,'MarkerFaceColor',color,'MarkerEdgeColor',color,'markersize', 15);
            text(board,X-0.75,Y-0.5,piece);
        end

        % Value changed function: YellowSquareDropDown
        function YellowSqAdd(app, event)
            piece = app.YellowSquareDropDown.Value;
            board = app.UIAxes;
            marker = 'S';
            color = 'Yellow';
            hold(board,'on');

            prompt = {'Enter x coordinate:','Enter y coordinate:'};
            title = 'Select an open space';
            answer = inputdlg(prompt,title);
            X = str2num(answer{1});
            Y = str2num(answer{2});
            
             while (X>8 || X<1 || Y>8 || Y<1) || (app.GB(X,Y)==1)
                answer = inputdlg(prompt,title);
                X = str2num(answer{1});
                Y = str2num(answer{2});
            end
            app.GB(X,Y) = 1;
            plot(board,X-0.5,Y-0.5,'marker',marker,'MarkerFaceColor',color,'MarkerEdgeColor',color,'markersize', 15);
            text(board,X-0.75,Y-0.5,piece);
            
        end

        % Value changed function: YellowCircleDropDown
        function YellowCirAdd(app, event)
            piece = app.YellowCircleDropDown.Value;
            board = app.UIAxes;
            marker = 'o';
            color = 'Yellow';
            hold(board,'on');

            prompt = {'Enter x coordinate:','Enter y coordinate:'};
            title = 'Select an open space';
            answer = inputdlg(prompt,title );
            X = str2num(answer{1});
            Y = str2num(answer{2});
            
            while (X>8 || X<1 || Y>8 || Y<1) || (app.GB(X,Y)==1)
                answer = inputdlg(prompt,title);
                X = str2num(answer{1});
                Y = str2num(answer{2});
            end
            app.GB(X,Y) = 1;
            plot(board,X-0.5,Y-0.5,'marker',marker,'MarkerFaceColor',color,'MarkerEdgeColor',color,'markersize', 15);
            text(board,X-0.75,Y-0.5,piece);
        end

        % Value changed function: RedTriangleDropDown
        function RedTriAdd(app, event)
            piece = app.RedTriangleDropDown.Value;
            board = app.UIAxes;
            marker = '^';
            color = 'Red';
            hold(board,'on');

            prompt = {'Enter x coordinate:','Enter y coordinate:'};
            title = 'Select an open space';
            answer = inputdlg(prompt,title );
            X = str2num(answer{1});
            Y = str2num(answer{2});
            
            while (X>8 || X<1 || Y>8 || Y<1) || (app.GB(X,Y)==1)
                answer = inputdlg(prompt,title);
                X = str2num(answer{1});
                Y = str2num(answer{2});
            end
            app.GB(X,Y) = 1;
            plot(board,X-0.5,Y-0.5,'marker',marker,'MarkerFaceColor',color,'MarkerEdgeColor',color,'markersize', 15);
            text(board,X-0.75,Y-0.5,piece);  
        end

        % Value changed function: RedSquareDropDown
        function RedSqAdd(app, event)
            piece = app.RedSquareDropDown.Value;
            board = app.UIAxes;
            marker = 'S';
            color = 'Red';
            hold(board,'on');

            prompt = {'Enter x coordinate:','Enter y coordinate:'};
            title = 'Select an open space';
            answer = inputdlg(prompt,title );
            X = str2num(answer{1});
            Y = str2num(answer{2});
            
            while (X>8 || X<1 || Y>8 || Y<1) || (app.GB(X,Y)==1)
                answer = inputdlg(prompt,title);
                X = str2num(answer{1});
                Y = str2num(answer{2});
            end
            app.GB(X,Y) = 1;
            plot(board,X-0.5,Y-0.5,'marker',marker,'MarkerFaceColor',color,'MarkerEdgeColor',color,'markersize', 15);
            text(board,X-0.75,Y-0.5,piece);
        end

        % Value changed function: RedCircleDropDown
        function RedCirAdd(app, event)
            piece = app.RedCircleDropDown.Value;
            board = app.UIAxes;
            marker = 'o';
            color = 'red';
            hold(board,'on');

            prompt = {'Enter x coordinate:','Enter y coordinate:'};
            title = 'Select an open space';
            answer = inputdlg(prompt,title );
            X = str2num(answer{1});
            Y = str2num(answer{2});
            
            while (X>8 || X<1 || Y>8 || Y<1) || (app.GB(X,Y)==1)
                answer = inputdlg(prompt,title);
                X = str2num(answer{1});
                Y = str2num(answer{2});
            end
            app.GB(X,Y) = 1;
            plot(board,X-0.5,Y-0.5,'marker',marker,'MarkerFaceColor',color,'MarkerEdgeColor',color,'markersize', 15);
            text(board,X-0.75,Y-0.5,piece);
        end

        % Value changed function: BlueTriangleDropDown
        function BlueTriAdd(app, event)
            piece = app.BlueTriangleDropDown.Value;
            board = app.UIAxes;
            marker = '^';
            color = 'blue';
            hold(board,'on');

            prompt = {'Enter x coordinate:','Enter y coordinate:'};
            title = 'Select an open space';
            answer = inputdlg(prompt,title );
            X = str2num(answer{1});
            Y = str2num(answer{2});
            
            while (X>8 || X<1 || Y>8 || Y<1) || (app.GB(X,Y)==1)
                answer = inputdlg(prompt,title);
                X = str2num(answer{1});
                Y = str2num(answer{2});
            end
            app.GB(X,Y) = 1;
            plot(board,X-0.5,Y-0.5,'marker',marker,'MarkerFaceColor',color,'MarkerEdgeColor',color,'markersize', 15);
            text(board,X-0.75,Y-0.5,piece);
        end

        % Value changed function: BlueSquareDropDown
        function BlueSqAdd(app, event)
            piece = app.BlueSquareDropDown.Value;
            board = app.UIAxes;
            marker = 's';
            color = 'blue';
            hold(board,'on');

            prompt = {'Enter x coordinate:','Enter y coordinate:'};
            title = 'Select an open space';
            answer = inputdlg(prompt,title );
            X = str2num(answer{1});
            Y = str2num(answer{2});
            
             while (X>8 || X<1 || Y>8 || Y<1) || (app.GB(X,Y)==1)
                answer = inputdlg(prompt,title);
                X = str2num(answer{1});
                Y = str2num(answer{2});
            end
            app.GB(X,Y) = 1;
            plot(board,X-0.5,Y-0.5,'marker',marker,'MarkerFaceColor',color,'MarkerEdgeColor',color,'markersize', 15);
            text(board,X-0.75,Y-0.5,piece);
            
        end

        % Value changed function: BlueCircleDropDown
        function BlueCirAdd(app, event)
            piece = app.BlueCircleDropDown.Value;
            board = app.UIAxes;
            marker = 'o';
            color = 'blue';
            hold(board,'on');

            prompt = {'Enter x coordinate:','Enter y coordinate:'};
            title = 'Select an open space';
            answer = inputdlg(prompt,title );
            X = str2num(answer{1});
            Y = str2num(answer{2});
            
             while (X>8 || X<1 || Y>8 || Y<1) || (app.GB(X,Y)==1)
                answer = inputdlg(prompt,title);
                X = str2num(answer{1});
                Y = str2num(answer{2});
            end
            app.GB(X,Y) = 1;
            plot(board,X-0.5,Y-0.5,'marker',marker,'MarkerFaceColor',color,'MarkerEdgeColor',color,'markersize', 15);
            text(board,X-0.75,Y-0.5,piece);
        end

        % Value changed function: GreenTriangleDropDown
        function GreenTriAdd(app, event)
            piece = app.GreenTriangleDropDown.Value;
            board = app.UIAxes;
            marker = '^';
            color = 'green';
            hold(board,'on');

            prompt = {'Enter x coordinate:','Enter y coordinate:'};
            title = 'Select an open space';
            answer = inputdlg(prompt,title );
            X = str2num(answer{1});
            Y = str2num(answer{2});
            
             while (X>8 || X<1 || Y>8 || Y<1) || (app.GB(X,Y)==1)
                answer = inputdlg(prompt,title);
                X = str2num(answer{1});
                Y = str2num(answer{2});
            end
            app.GB(X,Y) = 1;
            plot(board,X-0.5,Y-0.5,'marker',marker,'MarkerFaceColor',color,'MarkerEdgeColor',color,'markersize', 15);
            text(board,X-0.75,Y-0.5,piece);
        end

        % Value changed function: GreenSquareDropDown
        function GreenSqAdd(app, event)
            piece = app.GreenSquareDropDown.Value;
            board = app.UIAxes;
            marker = 'S';
            color = 'green';
            hold(board,'on');

            prompt = {'Enter x coordinate:','Enter y coordinate:'};
            title = 'Select an open space';
            answer = inputdlg(prompt,title );
            X = str2num(answer{1});
            Y = str2num(answer{2});
            
             while (X>8 || X<1 || Y>8 || Y<1) || (app.GB(X,Y)==1)
                answer = inputdlg(prompt,title);
                X = str2num(answer{1});
                Y = str2num(answer{2});
            end
            app.GB(X,Y) = 1;
            plot(board,X-0.5,Y-0.5,'marker',marker,'MarkerFaceColor',color,'MarkerEdgeColor',color,'markersize', 15);
            text(board,X-0.75,Y-0.5,piece);
            
        end

        % Value changed function: GreenCircleDropDown
        function GreenCirAdd(app, event)
            piece = app.GreenCircleDropDown.Value;
            board = app.UIAxes;
            marker = 'o';
            color = 'green';
            hold(board,'on');

            prompt = {'Enter x coordinate:','Enter y coordinate:'};
            title = 'Select an open space';
            answer = inputdlg(prompt,title );
            X = str2num(answer{1});
            Y = str2num(answer{2});
            
            while (X>8 || X<1 || Y>8 || Y<1) || (app.GB(X,Y)==1)
                answer = inputdlg(prompt,title);
                X = str2num(answer{1});
                Y = str2num(answer{2});
            end
            app.GB(X,Y) = 1;
            plot(board,X-0.5,Y-0.5,'marker',marker,'MarkerFaceColor',color,'MarkerEdgeColor',color,'markersize', 15);
            text(board,X-0.75,Y-0.5,piece);
            
        end

        % Button pushed function: RemovePieceButton
        function RemovePieceButtonPushed(app, event)
             prompt = {'Enter x coordinate:','Enter y coordinate:'};
            title = 'Enter a space to remove the piece';
            answer = inputdlg(prompt,title );
            X = str2num(answer{1});
            Y = str2num(answer{2});
            
            while (X>8 || X<1 || Y>8 || Y<1) || (app.GB(X,Y)==0)
                answer = inputdlg(prompt,title);
                X = str2num(answer{1});
                Y = str2num(answer{2});
            end
            plot(app.UIAxes,X-0.5,Y-0.5,'marker','s','MarkerFaceColor','white','MarkerEdgeColor','white','markersize', 25);
            app.GB(X,Y) = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChessUIFigure = uifigure;
            app.ChessUIFigure.Name = 'Chess';
            app.YellowTraingleDropDownLabel = uilabel(app.ChessUIFigure);
            app.YellowTraingleDropDownLabel.Text = 'Yellow Traingle';
            app.YellowTriangleDropDown = uidropdown(app.ChessUIFigure);
            app.YellowTriangleDropDown.Items = {'None', 'King', 'Queen', 'Bishop', 'Knight', 'Rook', 'Pawn'};
            app.YellowTriangleDropDown.ValueChangedFcn = createCallbackFcn(app, @YellowTriAdd, true);
            app.YellowTriangleDropDown.Value = 'None';
            app.YellowSquareDropDownLabel = uilabel(app.ChessUIFigure);
            app.YellowSquareDropDownLabel.Text = 'Yellow Square';
            app.YellowSquareDropDown = uidropdown(app.ChessUIFigure);
            app.YellowSquareDropDown.Items = {'None', 'King', 'Queen', 'Bishop', 'Knight', 'Rook', 'Pawn'};
            app.YellowSquareDropDown.ValueChangedFcn = createCallbackFcn(app, @YellowSqAdd, true);
            app.YellowSquareDropDown.Value = 'None';
            app.YellowCircleDropDownLabel = uilabel(app.ChessUIFigure);
            app.YellowCircleDropDownLabel.Text = 'Yellow Circle';
            app.YellowCircleDropDown = uidropdown(app.ChessUIFigure);
            app.YellowCircleDropDown.Items = {'None', 'King', 'Queen', 'Bishop', 'Knight', 'Rook', 'Pawn'};
            app.YellowCircleDropDown.ValueChangedFcn = createCallbackFcn(app, @YellowCirAdd, true);
            app.YellowCircleDropDown.Value = 'None';
            app.RedTriangleDropDownLabel = uilabel(app.ChessUIFigure);
            app.RedTriangleDropDownLabel.Text = 'Red Triangle';
            app.RedTriangleDropDown = uidropdown(app.ChessUIFigure);
            app.RedTriangleDropDown.Items = {'None', 'King', 'Queen', 'Bishop', 'Knight', 'Rook', 'Pawn'};
            app.RedTriangleDropDown.ValueChangedFcn = createCallbackFcn(app, @RedTriAdd, true);
            app.RedTriangleDropDown.Value = 'None';
            app.RedSquareDropDownLabel = uilabel(app.ChessUIFigure);
            app.RedSquareDropDownLabel.Text = 'Red Square';
            app.RedSquareDropDown = uidropdown(app.ChessUIFigure);
            app.RedSquareDropDown.Items = {'None', 'King', 'Queen', 'Bishop', 'Knight', 'Rook', 'Pawn'};
            app.RedSquareDropDown.ValueChangedFcn = createCallbackFcn(app, @RedSqAdd, true);
            app.RedSquareDropDown.Value = 'None';
            app.RedCircleDropDownLabel = uilabel(app.ChessUIFigure);
            app.RedCircleDropDownLabel.Text = 'Red Circle';
            app.RedCircleDropDown = uidropdown(app.ChessUIFigure);
            app.RedCircleDropDown.Items = {'None', 'King', 'Queen', 'Bishop', 'Knight', 'Rook', 'Pawn'};
            app.RedCircleDropDown.ValueChangedFcn = createCallbackFcn(app, @RedCirAdd, true);
            app.RedCircleDropDown.Value = 'None';
            app.BlueTriangleDropDownLabel = uilabel(app.ChessUIFigure);
            app.BlueTriangleDropDownLabel.Text = 'Blue Triangle';
            app.BlueTriangleDropDown = uidropdown(app.ChessUIFigure);
            app.BlueTriangleDropDown.Items = {'None', 'King', 'Queen', 'Bishop', 'Knight', 'Rook', 'Pawn'};
            app.BlueTriangleDropDown.ValueChangedFcn = createCallbackFcn(app, @BlueTriAdd, true);
            app.BlueTriangleDropDown.Value = 'None';
            app.BlueSquareDropDownLabel = uilabel(app.ChessUIFigure);
            app.BlueSquareDropDownLabel.Text = 'Blue Square';
            app.BlueSquareDropDown = uidropdown(app.ChessUIFigure);
            app.BlueSquareDropDown.Items = {'None', 'King', 'Queen', 'Bishop', 'Knight', 'Rook', 'Pawn'};
            app.BlueSquareDropDown.ValueChangedFcn = createCallbackFcn(app, @BlueSqAdd, true);
            app.BlueSquareDropDown.Value = 'None';
            app.BlueCircleDropDownLabel = uilabel(app.ChessUIFigure);
            app.BlueCircleDropDownLabel.Text = 'Blue Circle';
            app.BlueCircleDropDown = uidropdown(app.ChessUIFigure);
            app.BlueCircleDropDown.Items = {'None', 'King', 'Queen', 'Bishop', 'Knight', 'Rook', 'Pawn'};
            app.BlueCircleDropDown.ValueChangedFcn = createCallbackFcn(app, @BlueCirAdd, true);
            app.BlueCircleDropDown.Value = 'None';
            app.GreenTriangleDropDownLabel = uilabel(app.ChessUIFigure);
            app.GreenTriangleDropDownLabel.Text = 'Green Triangle';
            app.GreenTriangleDropDown = uidropdown(app.ChessUIFigure);
            app.GreenTriangleDropDown.Items = {'None', 'King', 'Queen', 'Bishop', 'Knight', 'Rook', 'Pawn'};
            app.GreenTriangleDropDown.ValueChangedFcn = createCallbackFcn(app, @GreenTriAdd, true);
            app.GreenTriangleDropDown.Value = 'None';
            app.GreenSquareDropDownLabel = uilabel(app.ChessUIFigure);
            app.GreenSquareDropDownLabel.Text = 'Green Square';
            app.GreenSquareDropDown = uidropdown(app.ChessUIFigure);
            app.GreenSquareDropDown.Items = {'None', 'King', 'Queen', 'Bishop', 'Knight', 'Rook', 'Pawn'};
            app.GreenSquareDropDown.ValueChangedFcn = createCallbackFcn(app, @GreenSqAdd, true);
            app.GreenSquareDropDown.Value = 'None';
            app.GreenCircleDropDownLabel = uilabel(app.ChessUIFigure);
            app.GreenCircleDropDownLabel.Text = 'Green Circle';
            app.GreenCircleDropDown = uidropdown(app.ChessUIFigure);
            app.GreenCircleDropDown.Items = {'None', 'King', 'Queen', 'Bishop', 'Knight', 'Rook', 'Pawn'};
            app.GreenCircleDropDown.ValueChangedFcn = createCallbackFcn(app, @GreenCirAdd, true);
            app.GreenCircleDropDown.Value = 'None';
            app.UIAxes = uiaxes(app.ChessUIFigure);
            xlabel(app.UIAxes, '1           2          3          4          5          6          7          8         ')
            ylabel(app.UIAxes, '1          2          3           4            5           6         7           8')
            app.UIAxes.XLim = [0 8];
            app.UIAxes.YLim = [0 8];
            app.UIAxes.XAxisLocation = 'top';
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 1 2 3 4 5 6 7];
            app.UIAxes.YTickLabel = '';
            app.AddPiecesLabel = uilabel(app.ChessUIFigure);
            app.AddPiecesLabel.Text = 'Add Pieces';
            app.MovePieceButton = uibutton(app.ChessUIFigure, 'push');
            app.MovePieceButton.Text = 'Move Piece';
            app.RemovePieceButton = uibutton(app.ChessUIFigure, 'push');
            app.RemovePieceButton.ButtonPushedFcn = createCallbackFcn(app, @RemovePieceButtonPushed, true);
            app.RemovePieceButton.Text = 'Remove Piece';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ChessGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChessUIFigure)

            % Execute the startup function
            runStartupFcn(app, @gameStartFN)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChessUIFigure)
        end
    end
end