classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        redCircle           matlab.ui.control.Button
        redSquare           matlab.ui.control.Button
        redTriangle         matlab.ui.control.Button
        blueCircle          matlab.ui.control.Button
        blueSquare          matlab.ui.control.Button
        blueTriangle        matlab.ui.control.Button
        greenCircle         matlab.ui.control.Button
        greenSquare         matlab.ui.control.Button
        greenTriangle       matlab.ui.control.Button
        yellowCircle        matlab.ui.control.Button
        yellowSquare        matlab.ui.control.Button
        yellowTriangle      matlab.ui.control.Button
        btn11               matlab.ui.control.Button
        btn12               matlab.ui.control.Button
        btn13               matlab.ui.control.Button
        btn14               matlab.ui.control.Button
        btn15               matlab.ui.control.Button
        btn16               matlab.ui.control.Button
        btn17               matlab.ui.control.Button
        btn18               matlab.ui.control.Button
        btn22               matlab.ui.control.Button
        btn23               matlab.ui.control.Button
        btn24               matlab.ui.control.Button
        btn25               matlab.ui.control.Button
        btn26               matlab.ui.control.Button
        btn27               matlab.ui.control.Button
        btn28               matlab.ui.control.Button
        btn31               matlab.ui.control.Button
        btn32               matlab.ui.control.Button
        btn33               matlab.ui.control.Button
        btn34               matlab.ui.control.Button
        btn35               matlab.ui.control.Button
        btn36               matlab.ui.control.Button
        btn37               matlab.ui.control.Button
        btn38               matlab.ui.control.Button
        btn41               matlab.ui.control.Button
        btn42               matlab.ui.control.Button
        btn43               matlab.ui.control.Button
        btn44               matlab.ui.control.Button
        btn45               matlab.ui.control.Button
        btn46               matlab.ui.control.Button
        btn47               matlab.ui.control.Button
        btn48               matlab.ui.control.Button
        btn51               matlab.ui.control.Button
        btn52               matlab.ui.control.Button
        btn53               matlab.ui.control.Button
        btn54               matlab.ui.control.Button
        btn55               matlab.ui.control.Button
        btn56               matlab.ui.control.Button
        btn57               matlab.ui.control.Button
        btn58               matlab.ui.control.Button
        btn61               matlab.ui.control.Button
        btn62               matlab.ui.control.Button
        btn63               matlab.ui.control.Button
        btn64               matlab.ui.control.Button
        btn65               matlab.ui.control.Button
        btn66               matlab.ui.control.Button
        btn67               matlab.ui.control.Button
        btn68               matlab.ui.control.Button
        btn71               matlab.ui.control.Button
        btn72               matlab.ui.control.Button
        btn73               matlab.ui.control.Button
        btn74               matlab.ui.control.Button
        btn75               matlab.ui.control.Button
        btn76               matlab.ui.control.Button
        btn77               matlab.ui.control.Button
        btn78               matlab.ui.control.Button
        btn81               matlab.ui.control.Button
        btn82               matlab.ui.control.Button
        btn83               matlab.ui.control.Button
        btn84               matlab.ui.control.Button
        btn85               matlab.ui.control.Button
        btn86               matlab.ui.control.Button
        btn87               matlab.ui.control.Button
        btn88               matlab.ui.control.Button
        YourHand            matlab.ui.control.Button
        RemovePieceButton   matlab.ui.control.Button
        btn21               matlab.ui.control.Button
        GameBoardLabel      matlab.ui.control.Label
        EncodePiecesLabel   matlab.ui.control.Label
        YourHandLabel       matlab.ui.control.Label
        DoneButton          matlab.ui.control.Button
        AddPieceButton      matlab.ui.control.Button
        RefreshButton       matlab.ui.control.Button
        CapturePieceButton  matlab.ui.control.Button
        MakeCheckButton     matlab.ui.control.Button
        DefendCheckButton   matlab.ui.control.Button
        TextAreaLabel       matlab.ui.control.Label
        HintTextArea        matlab.ui.control.TextArea
        Label               matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
        Label_6             matlab.ui.control.Label
        Label_7             matlab.ui.control.Label
        Label_8             matlab.ui.control.Label
        Label_9             matlab.ui.control.Label
    end


    properties (Access = public)
        moveReady=0;
        capture = 0;
        capX = 0;
        capY = 0;
        capReady = 0;
        ready = 0;
        encodedPieces = {"nil" "nil" ; "nil" "nil" ; "nil" "nil" ; "nil" "nil" ;
                         "nil" "nil" ; "nil" "nil" ; "nil" "nil" ; "nil" "nil" ;
                         "nil" "nil" ; "nil" "nil" ; "nil" "nil" ; "nil" "nil" };
        list = {"User King","User Queen","User Bishop","User Knight","User Rook","User Pawn", 
                    "Opponent King","Opponent Queen","Opponent Bishop","Opponent Knight","Opponent Rook","Opponent Pawn"};
        figList = {"redCircle","redSquare","redTriangle","blueCircle","blueSquare","blueTriangle",
                    "greenCircle","greenSquare","greenTriangle","yellowCircle","yellowSquare","yellowTriangle"}
        shapeList = {"Red Circle","Red Square","Red Triangle","Blue Circle","Blue Square","Blue Triangle",
                    "Green Circle","Green Square","Green Triangle","Yellow Circle","Yellow Square","Yellow Triangle"}
        handTeam = NaN;
        handNum = NaN;
        X_init = 0;
        Y_init = 0;
        pickedUp = 0;
        pickedUpTeam = 0;
        pickedUpType = 0;
        pickedUpNum = 0;
        
        listUsed = nan(numPieces*2,1);
        
        black = 0;
        gray =0 ;
    end

    properties (Access = private)
        
    end

    methods (Access = public)
    
        function text = encodePiece(app)            
            indx = listdlg('ListString',app.list,'PromptString','Select a piece','SelectionMode','single');
            text = app.list{indx};
            while text == ""
                indx = listdlg('ListString',app.list,'PromptString','Select a piece','SelectionMode','single');
                text = app.list{indx};
            end
            app.list{indx} = "";
            app.listUsed(indx) = 1;
        end
        
        function GBpushed(app,X,Y)
            global board;
            if app.capture == 1
                app.capX = X;
                app.capY= Y;
                app.capReady = 1;
            % place piece
            elseif (app.YourHand.Text ~= "")&&(isnan(board(X,Y).type)) 
                 %move arm
                placePiece(app,X,Y);
                enableAll(app);
                refreshPoss;
             %capture Piece
            elseif (app.YourHand.Text ~= "")&&(board(X,Y).team ~= app.handTeam)
                 placePiece(app,X,Y);
                 enableAll(app);
                 refreshPoss;
            % pickup piece
            elseif app.YourHand.Text == "" && ~isnan(board(X,Y).type) 
                refreshPoss;
                coordinates = possibleCoord(board(X,Y).possible);
                showMoves(app,coordinates);
                eval(['app.btn' num2str(X) num2str(Y) '.Enable = "on"']);
                eval(['app.btn' num2str(X) num2str(Y) '.BackgroundColor = [0 1 0]']);
                pickupPiece(app,X,Y);
                
            end
        end
        
        
    end

    methods (Access = private)
    
        function  pickupPiece(app,X,Y)
            global board;
                eval(['app.YourHand.Icon = app.btn' num2str(X) num2str(Y) '.Icon']);
                app.YourHand.Text = IDpiece(X,Y);
                app.handTeam = board(X,Y).team;
                app.handNum = board(X,Y).num;
                eval(['app.btn' num2str(X) num2str(Y) '.Icon = "" ']);
                eval(['app.btn' num2str(X) num2str(Y) '.Text = "" ']);
                
                app.pickedUpTeam = board(X,Y).team;
                app.pickedUpType = board(X,Y).type;
                app.pickedUpNum = board(X,Y).num;
                app.pickedUp = 1;
                app.X_init = X;
                app.Y_init = Y;
                
                %update global
                board(X,Y).team = NaN;
                board(X,Y).type = NaN;
                board(X,Y).num = NaN;
                board(X,Y).angle = NaN;
                board(X,Y).dist = NaN;
                board(X,Y).possible(1:8,1:8) = NaN;
        end
        
        function placePiece(app,X,Y)
            global board;
                eval(['app.btn' num2str(X) num2str(Y) '.Icon = app.YourHand.Icon'])
                eval(['app.btn' num2str(X) num2str(Y) '.Text = app.YourHand.Text']);
                
                
                refreshPoss;
                
                app.YourHand.Icon = '';
                app.YourHand.Text = '';
                
                if app.moveReady == 0 
                    move(app.X_init,app.Y_init,X,Y);
                end
                app.moveReady = 0;
                
                %update global
                board(X,Y).type = app.pickedUpType;
                board(X,Y).team = app.pickedUpTeam;
                board(X,Y).num = app.pickedUpNum;
                
                enableAll(app);
        end
        
        % Highlights buttons where it is possible to move
        function showMoves(app, possibleMoves)
            if isnan(possibleMoves(1))
                disableAll(app);
                return;
            end
            X = possibleMoves(:,1);
            Y = possibleMoves(:,2);
            len = size(X,1);
            disableAll(app);
            for k=1:len
                eval(['app.btn' num2str(X(k)) num2str(Y(k)) '.Enable = "on"']);
                eval(['app.btn' num2str(X(k)) num2str(Y(k)) '.BackgroundColor = [0 1 0]']);
            end           
        end
        
        function enableAll(app)
            for i=1:8
                for j=1:8
                    eval(['app.btn' num2str(i) num2str(j) '.Enable = "on"']);
                    if (mod(i,2)==mod(j,2))
                        eval(['app.btn' num2str(i) num2str(j) '.BackgroundColor = app.black']);
                    else
                        eval(['app.btn' num2str(i) num2str(j) '.BackgroundColor = app.gray']);
                    end
                end
            end
        end
        
        function disableAll(app)
            for i=1:8
                for j=1:8
                    eval(['app.btn' num2str(i) num2str(j) '.Enable = "off"']);
                    
                    
                end
            end
        end
        
    end
            

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.moveReady = 0;
           app.HintTextArea.Visible = 'off';
%            initGB;
           app.black = app.btn11.BackgroundColor;
           app.gray = app.btn12.BackgroundColor;
        end

        % Button pushed function: redCircle
        function redCircleButtonPushed(app, event)
            text = encodePiece(app);
            app.redCircle.Text = text;
            app.encodedPieces{1,1} = "Red Circle";
            app.encodedPieces{1,2} = text;
        end

        % Button pushed function: redSquare
        function redSquareButtonPushed(app, event)
            text = encodePiece(app);
            app.redSquare.Text = text;
            app.encodedPieces{2,1} = "Red Square";
            app.encodedPieces{2,2} = text;
        end

        % Button pushed function: redTriangle
        function redTriangleButtonPushed(app, event)
            text = encodePiece(app);
            app.redTriangle.Text = text;
            app.encodedPieces{3,1} = "Red Triangle";
            app.encodedPieces{3,2} = text;
        end

        % Button pushed function: blueCircle
        function blueCircleButtonPushed(app, event)
            text = encodePiece(app);
            app.blueCircle.Text = text;
            app.blueCircle.Icon = 'blueCircle.png';
            app.encodedPieces{4,1} = "Blue Circle";
            app.encodedPieces{4,2} = text;
        end

        % Button pushed function: blueSquare
        function blueSquareButtonPushed(app, event)
            text = encodePiece(app);
            app.blueSquare.Text = text;
            app.encodedPieces{5,1} = "Blue Square";
            app.encodedPieces{5,2} = text;
        end

        % Button pushed function: blueTriangle
        function blueTriangleButtonPushed(app, event)
            text = encodePiece(app);
            app.blueTriangle.Text = text;
            app.encodedPieces{6,1} = "Blue Triangle";
            app.encodedPieces{6,2} = text;
        end

        % Button pushed function: greenCircle
        function greenCircleButtonPushed(app, event)
            text = encodePiece(app);
            app.greenCircle.Text = text;
            app.encodedPieces{7,1} = "Green Circle";
            app.encodedPieces{7,2} = text;
        end

        % Button pushed function: greenSquare
        function greenSquareButtonPushed(app, event)
            text = encodePiece(app);
            app.greenSquare.Text = text;
            app.encodedPieces{8,1} = "Green Square";
            app.encodedPieces{8,2} = text;
        end

        % Button pushed function: greenTriangle
        function greenTriangleButtonPushed(app, event)
            text = encodePiece(app);
            app.greenTriangle.Text = text;
            app.encodedPieces{9,1} = "Green Triangle";
            app.encodedPieces{9,2} = text;
        end

        % Button pushed function: yellowCircle
        function yellowCircleButtonPushed(app, event)
            text = encodePiece(app);
            app.yellowCircle.Text = text;
            app.encodedPieces{10,1} = "Yellow Circle";
            app.encodedPieces{10,2} = text;
        end

        % Button pushed function: yellowSquare
        function yellowSquareButtonPushed(app, event)
            text = encodePiece(app);
            app.yellowSquare.Text = text;
            app.encodedPieces{11,1} = "Yellow Square";
            app.encodedPieces{11,2} = text;
        end

        % Button pushed function: yellowTriangle
        function yellowTriangleButtonPushed(app, event)
            text = encodePiece(app);
            app.yellowTriangle.Text = text;
            app.encodedPieces{12,1} = "Yellow Triangle";
            app.encodedPieces{12,2} = text;
        end

        % Button pushed function: btn11
        function btn11ButtonPushed(app, event)
           GBpushed(app,1,1);
        end

        % Button pushed function: btn12
        function btn12ButtonPushed(app, event)
             GBpushed(app,1,2);
        end

        % Button pushed function: btn13
        function btn13ButtonPushed(app, event)
            GBpushed(app,1,3);            
        end

        % Button pushed function: btn14
        function btn14ButtonPushed(app, event)
             GBpushed(app,1,4);            
        end

        % Button pushed function: btn15
        function btn15ButtonPushed(app, event)
            GBpushed(app,1,5);
        end

        % Button pushed function: btn16
        function btn16ButtonPushed(app, event)
            GBpushed(app,1,6);            
        end

        % Button pushed function: btn17
        function btn17ButtonPushed(app, event)
             GBpushed(app,1,7);            
        end

        % Button pushed function: btn18
        function btn18ButtonPushed(app, event)
             GBpushed(app,1,8);            
        end

        % Button pushed function: btn21
        function btn21ButtonPushed(app, event)
             GBpushed(app,2,1);            
        end

        % Button pushed function: btn22
        function btn22ButtonPushed(app, event)
             GBpushed(app,2,2);            
        end

        % Button pushed function: btn23
        function btn23ButtonPushed(app, event)
             GBpushed(app,2,3);            
        end

        % Button pushed function: btn24
        function btn24ButtonPushed(app, event)
             GBpushed(app,2,4);            
        end

        % Button pushed function: btn25
        function btn25ButtonPushed(app, event)
             GBpushed(app,2,5);            
        end

        % Button pushed function: btn26
        function btn26ButtonPushed(app, event)
            GBpushed(app,2,6);
        end

        % Button pushed function: btn27
        function btn27ButtonPushed(app, event)
            GBpushed(app,2,7);
        end

        % Button pushed function: btn28
        function btn28ButtonPushed(app, event)
            GBpushed(app,2,8);
        end

        % Button pushed function: btn31
        function btn31ButtonPushed(app, event)
            GBpushed(app,3,1);
        end

        % Button pushed function: btn32
        function btn32ButtonPushed(app, event)
            GBpushed(app,3,2);           
        end

        % Button pushed function: btn33
        function btn33ButtonPushed(app, event)
            GBpushed(app,3,3);
        end

        % Button pushed function: btn34
        function btn34ButtonPushed(app, event)
            GBpushed(app,3,4);
        end

        % Button pushed function: btn35
        function btn35ButtonPushed(app, event)
            GBpushed(app,3,5);
        end

        % Button pushed function: btn36
        function btn36ButtonPushed(app, event)
            GBpushed(app,3,6);
        end

        % Button pushed function: btn37
        function btn37ButtonPushed(app, event)
            GBpushed(app,3,7);
        end

        % Button pushed function: btn38
        function btn38ButtonPushed(app, event)
            GBpushed(app,3,8);
        end

        % Button pushed function: btn41
        function btn41ButtonPushed(app, event)
            GBpushed(app,4,1);
        end

        % Button pushed function: btn42
        function btn42ButtonPushed(app, event)
            GBpushed(app,4,2);
        end

        % Button pushed function: btn43
        function btn43ButtonPushed(app, event)
            GBpushed(app,4,3);
        end

        % Button pushed function: btn44
        function btn44ButtonPushed(app, event)
            GBpushed(app,4,4);
        end

        % Button pushed function: btn45
        function btn45ButtonPushed(app, event)
            GBpushed(app,4,5);
        end

        % Button pushed function: btn46
        function btn46ButtonPushed(app, event)
            GBpushed(app,4,6);
        end

        % Button pushed function: btn47
        function btn47ButtonPushed(app, event)
            GBpushed(app,4,7);
        end

        % Button pushed function: btn48
        function btn48ButtonPushed(app, event)
            GBpushed(app,4,8);
        end

        % Button pushed function: btn51
        function btn51ButtonPushed(app, event)
            GBpushed(app,5,1);
        end

        % Button pushed function: btn52
        function btn52ButtonPushed(app, event)
            GBpushed(app,5,2);
        end

        % Button pushed function: btn53
        function btn53ButtonPushed(app, event)
            GBpushed(app,5,3);
        end

        % Button pushed function: btn54
        function btn54ButtonPushed(app, event)
            GBpushed(app,5,4);
        end

        % Button pushed function: btn55
        function btn55ButtonPushed(app, event)
            GBpushed(app,5,5);
        end

        % Button pushed function: btn56
        function btn56ButtonPushed(app, event)
            GBpushed(app,5,6);
        end

        % Button pushed function: btn57
        function btn57ButtonPushed(app, event)
            GBpushed(app,5,7);
        end

        % Button pushed function: btn58
        function btn58ButtonPushed(app, event)
            GBpushed(app,5,8);
        end

        % Button pushed function: btn61
        function btn61ButtonPushed(app, event)
            GBpushed(app,6,1);
        end

        % Button pushed function: btn62
        function btn62ButtonPushed(app, event)
            GBpushed(app,6,2);
        end

        % Button pushed function: btn63
        function btn63ButtonPushed(app, event)
            GBpushed(app,6,3);
        end

        % Button pushed function: btn64
        function btn64ButtonPushed(app, event)
            GBpushed(app,6,4);
        end

        % Button pushed function: btn65
        function btn65ButtonPushed(app, event)
            GBpushed(app,6,5);
        end

        % Button pushed function: btn66
        function btn66ButtonPushed(app, event)
            GBpushed(app,6,6);
        end

        % Button pushed function: btn67
        function btn67ButtonPushed(app, event)
            GBpushed(app,6,7);
        end

        % Button pushed function: btn68
        function btn68ButtonPushed(app, event)
            GBpushed(app,6,8);
        end

        % Button pushed function: btn71
        function btn71ButtonPushed(app, event)
            GBpushed(app,7,1);
        end

        % Button pushed function: btn72
        function btn72ButtonPushed(app, event)
            GBpushed(app,7,2);
        end

        % Button pushed function: btn73
        function btn73ButtonPushed(app, event)
            GBpushed(app,7,3);
        end

        % Button pushed function: btn74
        function btn74ButtonPushed(app, event)
            GBpushed(app,7,4);
        end

        % Button pushed function: btn75
        function btn75ButtonPushed(app, event)
            GBpushed(app,7,5);
        end

        % Button pushed function: btn76
        function btn76ButtonPushed(app, event)
            GBpushed(app,7,6);
        end

        % Button pushed function: btn77
        function btn77ButtonPushed(app, event)
            GBpushed(app,7,7);
        end

        % Button pushed function: btn78
        function btn78ButtonPushed(app, event)
            GBpushed(app,7,8);
        end

        % Button pushed function: btn81
        function btn81ButtonPushed(app, event)
            GBpushed(app,8,1);
        end

        % Button pushed function: btn82
        function btn82ButtonPushed(app, event)
            GBpushed(app,8,2);
        end

        % Button pushed function: btn83
        function btn83ButtonPushed(app, event)
            GBpushed(app,8,3);
        end

        % Button pushed function: btn84
        function btn84ButtonPushed(app, event)
            GBpushed(app,8,4);
        end

        % Button pushed function: btn85
        function btn85ButtonPushed(app, event)
            GBpushed(app,8,5);
        end

        % Button pushed function: btn86
        function btn86ButtonPushed(app, event)
            GBpushed(app,8,6);
        end

        % Button pushed function: btn87
        function btn87ButtonPushed(app, event)
            GBpushed(app,8,7);
        end

        % Button pushed function: btn88
        function btn88ButtonPushed(app, event)
            GBpushed(app,8,8);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
%             for ii = 1:size(app.encodedPieces,1)
%                 if app.encodedPieces{ii,1} == "nil"
%                     idx = find(isnan(app.listUsed),1);
%                     app.listUsed(idx) = 1;
%                     app.encodedPieces{ii,1} = app.shapeList{idx};
%                     app.encodedPieces{ii,2} = app.list{idx};
%                     eval(strcat("app.", app.figList{ii}, ".Text = app.list{idx}"));
%                 end
%             end
            
            app.ready = 1;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            global board;
            for i=1:8
                for j=1:8
                    if( ~isnan(board(i,j).type) ) 
                        text = IDpiece(i,j);
                        icon = decodeIcon(app.encodedPieces, text);
                        eval(['app.btn' num2str(i) num2str(j) '.Text = text']);
                        eval(['app.btn' num2str(i) num2str(j) '.Icon = icon']);
                    else
                        text = "";
                        icon = "";
                        eval(['app.btn' num2str(i) num2str(j) '.Text = text']);
                        eval(['app.btn' num2str(i) num2str(j) '.Icon = icon']);
                    end
                end
            end
        end

        % Button pushed function: AddPieceButton
        function AddPieceButtonPushed(app, event)
             piece_list = {"User King","User Queen","User Bishop","User Knight","User Rook","User Pawn", 
                    "Opponent King","Opponent Queen","Opponent Bishop","Opponent Knight","Opponent Rook","Opponent Pawn"};
            indx = listdlg('ListString',piece_list,'PromptString','Select a piece','SelectionMode','single');
            piece = piece_list{indx};
%             indx = listdlg('ListString',app.figList,'PromptString','Select a piece','SelectionMode','single');
%             fig = app.figList{indx};
            
            ind = strcmpi([app.encodedPieces{:,2}],piece_list{indx});
            if any(ind)
                fig = app.figList{ind};
            else
                fig = app.figList{1};
            end
                
            app.YourHand.Text = piece;
            app.YourHand.Icon = strcat(fig,".png");
            piece = char(piece);
            if piece(1) == 'U'
                app.handTeam = us;
            else
                app.handTeam = them;
            end
            
            app.pickedUpType = retnum(piece);
            if piece(1) == 'U'
                app.pickedUpTeam = us;
            else
                app.pickedUpTeam = them;
            end
            app.pickedUpNum = 1;
            
            app.moveReady = 1;
        end

        % Button pushed function: RemovePieceButton
        function RemovePieceButtonPushed(app, event)
            app.YourHand.Icon = "";
            app.YourHand.Text = "";
            enableAll(app);
        end

        % Button pushed function: CapturePieceButton
        function CapturePieceButtonPushed(app, event)
            app.capture = 1;
            app.HintTextArea.Visible = 'on';
            app.HintTextArea.Value = {"Select a piece to capture"};
            while app.capReady == 0 
                drawnow;
            end
            coord = captureOpp(app.capX,app.capY);
            if coord ~= 0
                X= coord(1);
                Y= coord(2);
                
                pickupPiece(app,X,Y);
                placePiece(app,app.capX,app.capY);
            else
                app.capture = 0;
                app.capReady = 0;
                app.HintTextArea.Visible = 'off';
                return;
            end
            app.HintTextArea.Visible = 'off';
            app.capture = 0;
            app.capReady =0 ;
        end

        % Button pushed function: MakeCheckButton
        function MakeCheckButtonPushed(app, event)
            app.capture = 1;
            app.HintTextArea.Visible = 'on';
            app.HintTextArea.Value = {"Select a piece to put the king in check"};
            while app.capReady == 0 
                drawnow;
            end
            [X, Y] = makeCheck(app.capX,app.capY);

            if isnan(X)
                app.HintTextArea.Visible = 'off';
                app.capture = 0;
                app.capReady = 0;
                return;
            end
            
            pickupPiece(app,app.capX,app.capY);
            placePiece(app,X,Y);
            app.capture = 0;
            app.capReady =0;
            app.HintTextArea.Visible = 'off';

        end

        % Button pushed function: DefendCheckButton
        function DefendCheckButtonPushed(app, event)
            global board;

            
            [x1, y1, x2, y2] = defendCheck;
            
            if isnan(x1)
                app.moveReady = 0; 
                return;
            end
            
            pickupPiece(app,x1,y1);
            placePiece(app,x2,y2);
            return;
%             
%             [moveX, moveY] = find(~isnan(board(Xkings,Ykings).possible),1);
%             
%             if isempty(moveX)
%                 return;
%             end
%             
%             move(Xkings,Ykings,moveX,moveY);
%             return;
%             
%             if board(Xkings(1),Ykings(1)).team == us
%                 Xi = Xkings(1);
%                 Yi = Ykings(1);
%             else
%                 Xi = Xkings(2);
%                 Yi = Ykings(2);
%             end
%             coord = possibleCoord(board(Xi,Yi).possible);
%             Xf = coord(1,1);
%             Yf = coord(1,2);
% 
%             pickupPiece(app,Xi,Yi);
%             placePiece(app,Xf,Yf);
%             move(Xi,Yi,Xf,Xf);
%             app.moveReady = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.redCircle = uibutton(app.UIFigure, 'push');
            app.redCircle.ButtonPushedFcn = createCallbackFcn(app, @redCircleButtonPushed, true);
            app.redCircle.Icon = 'redCircle.png';
            app.redCircle.Text = '';
            app.redSquare = uibutton(app.UIFigure, 'push');
            app.redSquare.ButtonPushedFcn = createCallbackFcn(app, @redSquareButtonPushed, true);
            app.redSquare.Icon = 'redSquare.png';
            app.redSquare.Text = '';
            app.redTriangle = uibutton(app.UIFigure, 'push');
            app.redTriangle.ButtonPushedFcn = createCallbackFcn(app, @redTriangleButtonPushed, true);
            app.redTriangle.Icon = 'redTriangle.png';
            app.redTriangle.Text = '';
            app.blueCircle = uibutton(app.UIFigure, 'push');
            app.blueCircle.ButtonPushedFcn = createCallbackFcn(app, @blueCircleButtonPushed, true);
            app.blueCircle.Icon = 'blueCircle.png';
            app.blueCircle.Text = '';
            app.blueSquare = uibutton(app.UIFigure, 'push');
            app.blueSquare.ButtonPushedFcn = createCallbackFcn(app, @blueSquareButtonPushed, true);
            app.blueSquare.Icon = 'blueSquare.png';
            app.blueSquare.Text = '';
            app.blueTriangle = uibutton(app.UIFigure, 'push');
            app.blueTriangle.ButtonPushedFcn = createCallbackFcn(app, @blueTriangleButtonPushed, true);
            app.blueTriangle.Icon = 'blueTriangle.png';
            app.blueTriangle.Text = '';
            app.greenCircle = uibutton(app.UIFigure, 'push');
            app.greenCircle.ButtonPushedFcn = createCallbackFcn(app, @greenCircleButtonPushed, true);
            app.greenCircle.Icon = 'greenCircle.png';
            app.greenCircle.Text = '';
            app.greenSquare = uibutton(app.UIFigure, 'push');
            app.greenSquare.ButtonPushedFcn = createCallbackFcn(app, @greenSquareButtonPushed, true);
            app.greenSquare.Icon = 'greenSquare.png';
            app.greenSquare.Text = '';
            app.greenTriangle = uibutton(app.UIFigure, 'push');
            app.greenTriangle.ButtonPushedFcn = createCallbackFcn(app, @greenTriangleButtonPushed, true);
            app.greenTriangle.Icon = 'greenTriangle.png';
            app.greenTriangle.Text = '';
            app.yellowCircle = uibutton(app.UIFigure, 'push');
            app.yellowCircle.ButtonPushedFcn = createCallbackFcn(app, @yellowCircleButtonPushed, true);
            app.yellowCircle.Icon = 'yellowCircle.png';
            app.yellowCircle.Text = '';
            app.yellowSquare = uibutton(app.UIFigure, 'push');
            app.yellowSquare.ButtonPushedFcn = createCallbackFcn(app, @yellowSquareButtonPushed, true);
            app.yellowSquare.Icon = 'yellowSquare.png';
            app.yellowSquare.Text = '';
            app.yellowTriangle = uibutton(app.UIFigure, 'push');
            app.yellowTriangle.ButtonPushedFcn = createCallbackFcn(app, @yellowTriangleButtonPushed, true);
            app.yellowTriangle.Icon = 'yellowTriangle.png';
            app.yellowTriangle.Text = '';
            app.btn11 = uibutton(app.UIFigure, 'push');
            app.btn11.ButtonPushedFcn = createCallbackFcn(app, @btn11ButtonPushed, true);
            app.btn11.Text = '';
            app.btn12 = uibutton(app.UIFigure, 'push');
            app.btn12.ButtonPushedFcn = createCallbackFcn(app, @btn12ButtonPushed, true);
            app.btn12.Text = '';
            app.btn13 = uibutton(app.UIFigure, 'push');
            app.btn13.ButtonPushedFcn = createCallbackFcn(app, @btn13ButtonPushed, true);
            app.btn13.Text = '';
            app.btn14 = uibutton(app.UIFigure, 'push');
            app.btn14.ButtonPushedFcn = createCallbackFcn(app, @btn14ButtonPushed, true);
            app.btn14.Text = '';
            app.btn15 = uibutton(app.UIFigure, 'push');
            app.btn15.ButtonPushedFcn = createCallbackFcn(app, @btn15ButtonPushed, true);
            app.btn15.Text = '';
            app.btn16 = uibutton(app.UIFigure, 'push');
            app.btn16.ButtonPushedFcn = createCallbackFcn(app, @btn16ButtonPushed, true);
            app.btn16.Text = '';
            app.btn17 = uibutton(app.UIFigure, 'push');
            app.btn17.ButtonPushedFcn = createCallbackFcn(app, @btn17ButtonPushed, true);
            app.btn17.Text = '';
            app.btn18 = uibutton(app.UIFigure, 'push');
            app.btn18.ButtonPushedFcn = createCallbackFcn(app, @btn18ButtonPushed, true);
            app.btn18.Text = '';
            app.btn22 = uibutton(app.UIFigure, 'push');
            app.btn22.ButtonPushedFcn = createCallbackFcn(app, @btn22ButtonPushed, true);
            app.btn22.Text = '';
            app.btn23 = uibutton(app.UIFigure, 'push');
            app.btn23.ButtonPushedFcn = createCallbackFcn(app, @btn23ButtonPushed, true);
            app.btn23.Text = '';
            app.btn24 = uibutton(app.UIFigure, 'push');
            app.btn24.ButtonPushedFcn = createCallbackFcn(app, @btn24ButtonPushed, true);
            app.btn24.Text = '';
            app.btn25 = uibutton(app.UIFigure, 'push');
            app.btn25.ButtonPushedFcn = createCallbackFcn(app, @btn25ButtonPushed, true);
            app.btn25.Text = '';
            app.btn26 = uibutton(app.UIFigure, 'push');
            app.btn26.ButtonPushedFcn = createCallbackFcn(app, @btn26ButtonPushed, true);
            app.btn26.Text = '';
            app.btn27 = uibutton(app.UIFigure, 'push');
            app.btn27.ButtonPushedFcn = createCallbackFcn(app, @btn27ButtonPushed, true);
            app.btn27.Text = '';
            app.btn28 = uibutton(app.UIFigure, 'push');
            app.btn28.ButtonPushedFcn = createCallbackFcn(app, @btn28ButtonPushed, true);
            app.btn28.Text = '';
            app.btn31 = uibutton(app.UIFigure, 'push');
            app.btn31.ButtonPushedFcn = createCallbackFcn(app, @btn31ButtonPushed, true);
            app.btn31.Text = '';
            app.btn32 = uibutton(app.UIFigure, 'push');
            app.btn32.ButtonPushedFcn = createCallbackFcn(app, @btn32ButtonPushed, true);
            app.btn32.Text = '';
            app.btn33 = uibutton(app.UIFigure, 'push');
            app.btn33.ButtonPushedFcn = createCallbackFcn(app, @btn33ButtonPushed, true);
            app.btn33.Text = '';
            app.btn34 = uibutton(app.UIFigure, 'push');
            app.btn34.ButtonPushedFcn = createCallbackFcn(app, @btn34ButtonPushed, true);
            app.btn34.Text = '';
            app.btn35 = uibutton(app.UIFigure, 'push');
            app.btn35.ButtonPushedFcn = createCallbackFcn(app, @btn35ButtonPushed, true);
            app.btn35.Text = '';
            app.btn36 = uibutton(app.UIFigure, 'push');
            app.btn36.ButtonPushedFcn = createCallbackFcn(app, @btn36ButtonPushed, true);
            app.btn36.Text = '';
            app.btn37 = uibutton(app.UIFigure, 'push');
            app.btn37.ButtonPushedFcn = createCallbackFcn(app, @btn37ButtonPushed, true);
            app.btn37.Text = '';
            app.btn38 = uibutton(app.UIFigure, 'push');
            app.btn38.ButtonPushedFcn = createCallbackFcn(app, @btn38ButtonPushed, true);
            app.btn38.Text = '';
            app.btn41 = uibutton(app.UIFigure, 'push');
            app.btn41.ButtonPushedFcn = createCallbackFcn(app, @btn41ButtonPushed, true);
            app.btn41.Text = '';
            app.btn42 = uibutton(app.UIFigure, 'push');
            app.btn42.ButtonPushedFcn = createCallbackFcn(app, @btn42ButtonPushed, true);
            app.btn42.Text = '';
            app.btn43 = uibutton(app.UIFigure, 'push');
            app.btn43.ButtonPushedFcn = createCallbackFcn(app, @btn43ButtonPushed, true);
            app.btn43.Text = '';
            app.btn44 = uibutton(app.UIFigure, 'push');
            app.btn44.ButtonPushedFcn = createCallbackFcn(app, @btn44ButtonPushed, true);
            app.btn44.Text = '';
            app.btn45 = uibutton(app.UIFigure, 'push');
            app.btn45.ButtonPushedFcn = createCallbackFcn(app, @btn45ButtonPushed, true);
            app.btn45.Text = '';
            app.btn46 = uibutton(app.UIFigure, 'push');
            app.btn46.ButtonPushedFcn = createCallbackFcn(app, @btn46ButtonPushed, true);
            app.btn46.Text = '';
            app.btn47 = uibutton(app.UIFigure, 'push');
            app.btn47.ButtonPushedFcn = createCallbackFcn(app, @btn47ButtonPushed, true);
            app.btn47.Text = '';
            app.btn48 = uibutton(app.UIFigure, 'push');
            app.btn48.ButtonPushedFcn = createCallbackFcn(app, @btn48ButtonPushed, true);
            app.btn48.Text = '';
            app.btn51 = uibutton(app.UIFigure, 'push');
            app.btn51.ButtonPushedFcn = createCallbackFcn(app, @btn51ButtonPushed, true);
            app.btn51.Text = '';
            app.btn52 = uibutton(app.UIFigure, 'push');
            app.btn52.ButtonPushedFcn = createCallbackFcn(app, @btn52ButtonPushed, true);
            app.btn52.Text = '';
            app.btn53 = uibutton(app.UIFigure, 'push');
            app.btn53.ButtonPushedFcn = createCallbackFcn(app, @btn53ButtonPushed, true);
            app.btn53.Text = '';
            app.btn54 = uibutton(app.UIFigure, 'push');
            app.btn54.ButtonPushedFcn = createCallbackFcn(app, @btn54ButtonPushed, true);
            app.btn54.Text = '';
            app.btn55 = uibutton(app.UIFigure, 'push');
            app.btn55.ButtonPushedFcn = createCallbackFcn(app, @btn55ButtonPushed, true);
            app.btn55.Text = '';
            app.btn56 = uibutton(app.UIFigure, 'push');
            app.btn56.ButtonPushedFcn = createCallbackFcn(app, @btn56ButtonPushed, true);
            app.btn56.Text = '';
            app.btn57 = uibutton(app.UIFigure, 'push');
            app.btn57.ButtonPushedFcn = createCallbackFcn(app, @btn57ButtonPushed, true);
            app.btn57.Text = '';
            app.btn58 = uibutton(app.UIFigure, 'push');
            app.btn58.ButtonPushedFcn = createCallbackFcn(app, @btn58ButtonPushed, true);
            app.btn58.Text = '';
            app.btn61 = uibutton(app.UIFigure, 'push');
            app.btn61.ButtonPushedFcn = createCallbackFcn(app, @btn61ButtonPushed, true);
            app.btn61.Text = '';
            app.btn62 = uibutton(app.UIFigure, 'push');
            app.btn62.ButtonPushedFcn = createCallbackFcn(app, @btn62ButtonPushed, true);
            app.btn62.Text = '';
            app.btn63 = uibutton(app.UIFigure, 'push');
            app.btn63.ButtonPushedFcn = createCallbackFcn(app, @btn63ButtonPushed, true);
            app.btn63.Text = '';
            app.btn64 = uibutton(app.UIFigure, 'push');
            app.btn64.ButtonPushedFcn = createCallbackFcn(app, @btn64ButtonPushed, true);
            app.btn64.Text = '';
            app.btn65 = uibutton(app.UIFigure, 'push');
            app.btn65.ButtonPushedFcn = createCallbackFcn(app, @btn65ButtonPushed, true);
            app.btn65.Text = '';
            app.btn66 = uibutton(app.UIFigure, 'push');
            app.btn66.ButtonPushedFcn = createCallbackFcn(app, @btn66ButtonPushed, true);
            app.btn66.Text = '';
            app.btn67 = uibutton(app.UIFigure, 'push');
            app.btn67.ButtonPushedFcn = createCallbackFcn(app, @btn67ButtonPushed, true);
            app.btn67.Text = '';
            app.btn68 = uibutton(app.UIFigure, 'push');
            app.btn68.ButtonPushedFcn = createCallbackFcn(app, @btn68ButtonPushed, true);
            app.btn68.Text = '';
            app.btn71 = uibutton(app.UIFigure, 'push');
            app.btn71.ButtonPushedFcn = createCallbackFcn(app, @btn71ButtonPushed, true);
            app.btn71.Text = '';
            app.btn72 = uibutton(app.UIFigure, 'push');
            app.btn72.ButtonPushedFcn = createCallbackFcn(app, @btn72ButtonPushed, true);
            app.btn72.Text = '';
            app.btn73 = uibutton(app.UIFigure, 'push');
            app.btn73.ButtonPushedFcn = createCallbackFcn(app, @btn73ButtonPushed, true);
            app.btn73.Text = '';
            app.btn74 = uibutton(app.UIFigure, 'push');
            app.btn74.ButtonPushedFcn = createCallbackFcn(app, @btn74ButtonPushed, true);
            app.btn74.Text = '';
            app.btn75 = uibutton(app.UIFigure, 'push');
            app.btn75.ButtonPushedFcn = createCallbackFcn(app, @btn75ButtonPushed, true);
            app.btn75.Text = '';
            app.btn76 = uibutton(app.UIFigure, 'push');
            app.btn76.ButtonPushedFcn = createCallbackFcn(app, @btn76ButtonPushed, true);
            app.btn76.Text = '';
            app.btn77 = uibutton(app.UIFigure, 'push');
            app.btn77.ButtonPushedFcn = createCallbackFcn(app, @btn77ButtonPushed, true);
            app.btn77.Text = '';
            app.btn78 = uibutton(app.UIFigure, 'push');
            app.btn78.ButtonPushedFcn = createCallbackFcn(app, @btn78ButtonPushed, true);
            app.btn78.Text = '';
            app.btn81 = uibutton(app.UIFigure, 'push');
            app.btn81.ButtonPushedFcn = createCallbackFcn(app, @btn81ButtonPushed, true);
            app.btn81.Text = '';
            app.btn82 = uibutton(app.UIFigure, 'push');
            app.btn82.ButtonPushedFcn = createCallbackFcn(app, @btn82ButtonPushed, true);
            app.btn82.Text = '';
            app.btn83 = uibutton(app.UIFigure, 'push');
            app.btn83.ButtonPushedFcn = createCallbackFcn(app, @btn83ButtonPushed, true);
            app.btn83.Text = '';
            app.btn84 = uibutton(app.UIFigure, 'push');
            app.btn84.ButtonPushedFcn = createCallbackFcn(app, @btn84ButtonPushed, true);
            app.btn84.Text = '';
            app.btn85 = uibutton(app.UIFigure, 'push');
            app.btn85.ButtonPushedFcn = createCallbackFcn(app, @btn85ButtonPushed, true);
            app.btn85.Text = '';
            app.btn86 = uibutton(app.UIFigure, 'push');
            app.btn86.ButtonPushedFcn = createCallbackFcn(app, @btn86ButtonPushed, true);
            app.btn86.Text = '';
            app.btn87 = uibutton(app.UIFigure, 'push');
            app.btn87.ButtonPushedFcn = createCallbackFcn(app, @btn87ButtonPushed, true);
            app.btn87.Text = '';
            app.btn88 = uibutton(app.UIFigure, 'push');
            app.btn88.ButtonPushedFcn = createCallbackFcn(app, @btn88ButtonPushed, true);
            app.btn88.Text = '';
            app.YourHand = uibutton(app.UIFigure, 'push');
            app.YourHand.Text = '';
            app.RemovePieceButton = uibutton(app.UIFigure, 'push');
            app.RemovePieceButton.ButtonPushedFcn = createCallbackFcn(app, @RemovePieceButtonPushed, true);
            app.RemovePieceButton.Text = 'Remove Piece';
            app.btn21 = uibutton(app.UIFigure, 'push');
            app.btn21.ButtonPushedFcn = createCallbackFcn(app, @btn21ButtonPushed, true);
            app.btn21.Text = '';
            app.GameBoardLabel = uilabel(app.UIFigure);
            app.GameBoardLabel.Text = 'GameBoard';
            app.EncodePiecesLabel = uilabel(app.UIFigure);
            app.EncodePiecesLabel.Text = 'Encode Pieces';
            app.YourHandLabel = uilabel(app.UIFigure);
            app.YourHandLabel.Text = 'Your Hand';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.AddPieceButton = uibutton(app.UIFigure, 'push');
            app.AddPieceButton.ButtonPushedFcn = createCallbackFcn(app, @AddPieceButtonPushed, true);
            app.AddPieceButton.Text = 'Add Piece';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.CapturePieceButton = uibutton(app.UIFigure, 'push');
            app.CapturePieceButton.ButtonPushedFcn = createCallbackFcn(app, @CapturePieceButtonPushed, true);
            app.CapturePieceButton.Text = 'Capture Piece';
            app.MakeCheckButton = uibutton(app.UIFigure, 'push');
            app.MakeCheckButton.ButtonPushedFcn = createCallbackFcn(app, @MakeCheckButtonPushed, true);
            app.MakeCheckButton.Text = 'Make Check';
            app.DefendCheckButton = uibutton(app.UIFigure, 'push');
            app.DefendCheckButton.ButtonPushedFcn = createCallbackFcn(app, @DefendCheckButtonPushed, true);
            app.DefendCheckButton.Text = 'Defend Check';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = '';
            app.HintTextArea = uitextarea(app.UIFigure);
            app.HintTextArea.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1            2             3             4            5             6            7             8  ';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '1';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '2';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '3';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '4';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '5';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '6';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '7';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '8';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end